classdef Wiper_Mechanism < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TimeofexecutionsecSliderLabel  matlab.ui.control.Label
        TimeofexecutionsecSlider       matlab.ui.control.Slider
        RunButton                      matlab.ui.control.Button
        EvaluateButton                 matlab.ui.control.Button
        AngularVelocityLabel           matlab.ui.control.Label
        AngularVelocityEditField       matlab.ui.control.NumericEditField
        CranklinklengthLabel           matlab.ui.control.Label
        CranklinklengthEditField       matlab.ui.control.NumericEditField
        ConnectinglinklengthEditFieldLabel  matlab.ui.control.Label
        ConnectinglinklengthEditField  matlab.ui.control.NumericEditField
        WiperlengthsLabel              matlab.ui.control.Label
        WiperlengthsEditField          matlab.ui.control.NumericEditField
        wiperangleonesideindegreesSliderLabel  matlab.ui.control.Label
        wiperangleonesideindegreesSlider  matlab.ui.control.Slider
        SliderbaselinklengthEditFieldLabel  matlab.ui.control.Label
        SliderbaselinklengthEditField  matlab.ui.control.NumericEditField
        DistancebtwwipersEditFieldLabel  matlab.ui.control.Label
        DistancebtwwipersEditField     matlab.ui.control.NumericEditField
        WIPERMECHANISMLabel            matlab.ui.control.Label
        ValidLampLabel                 matlab.ui.control.Label
        ValidLamp                      matlab.ui.control.Lamp
        OUTPUTTextAreaLabel            matlab.ui.control.Label
        OUTPUTTextArea                 matlab.ui.control.TextArea
        CLEARButton                    matlab.ui.control.Button
        AngularVelocityvstimeCheckBox  matlab.ui.control.CheckBox
        WiperanglevstimeCheckBox       matlab.ui.control.CheckBox
        ThetavstimeCheckBox            matlab.ui.control.CheckBox
        LinklengethsmLabel             matlab.ui.control.Label
        GraphsLabel                    matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        filenameEditFieldLabel         matlab.ui.control.Label
        filenameEditField              matlab.ui.control.EditField
        LoadButton                     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TimeofexecutionsecSlider
        function TimeofexecutionsecSliderValueChanged(app, event)
            % value = app.TimeofexecutionsecSlider.Value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
                
                % length in m
                a = app.CranklinklengthEditField.Value;      % crank link length
                b = app.ConnectinglinklengthEditField.Value; % connencting link length
                
                % wiper base parameters
                base_val = app.SliderbaselinklengthEditField.Value;
                width = app.DistancebtwwipersEditField.Value;
                buffer = (base_val-width)/2;
                
                % wiper parameters
                l = app.WiperlengthsEditField.Value;
                wip_range = round(app.wiperangleonesideindegreesSlider.Value);
                h = a/tand(wip_range);
                k = 2*l;
                
                % time of execution
                T = round(app.TimeofexecutionsecSlider.Value);
                iter = T*10;
                
                % the angular speed of the crank in rad/s
                w = app.AngularVelocityEditField.Value;
                
                
                % parameters of the plot
                neg_x = -(a + 1);
                pos_x = (a + b + base_val + 1);
                neg_y = -(a + 1);
                if((l + h) > a)
                    pos_y = (l + h + 1);
                else
                    pos_y = a+1;
                end
                axis(gca, 'equal'); % the aspect ratio
                axis([neg_x pos_x neg_y pos_y]);  % the limits
                
                
                % fixed joint
                P1 = [0 0];

                % the point P2s trajectory
                P2_traj = viscircles([0 0],a,'Linestyle','--');
                
                % locations of hinges
                O = [b 0];
                H1 = O + [buffer h];
                H2 = O + [(buffer + width) h];
                
                H1_circ = viscircles(H1,0.1);
                H2_circ = viscircles(H2,0.1);
                
                % locations of expected extermities
                M1 = H1 + k*[0 1];
                M2 = H2 + k*[0 1];
                ER1 = H1 + k*[sind(wip_range) cosd(wip_range)];
                EL1 = H1 + k*[-sind(wip_range) cosd(wip_range)];
                ER2 = H2 + k*[sind(wip_range) cosd(wip_range)];
                EL2 = H2 + k*[-sind(wip_range) cosd(wip_range)];
                
                % drawing those lines
                PM1 = line([H1(1) M1(1)],[H1(2) M1(2)],'Color','blue','Linestyle','--');
                PM2 = line([H2(1) M2(1)],[H2(2) M2(2)],'Color','blue','Linestyle','--');
                PER1 = line([H1(1) ER1(1)],[H1(2) ER1(2)],'Color','blue','Linestyle','--');
                PEL1 = line([H1(1) EL1(1)],[H1(2) EL1(2)],'Color','blue','Linestyle','--');
                PER2 = line([H2(1) ER2(1)],[H2(2) ER2(2)],'Color','blue','Linestyle','--');
                PEL2 = line([H2(1) EL2(1)],[H2(2) EL2(2)],'Color','blue','Linestyle','--');
                
                % for other plots
                w_value = w*ones(iter);
                time = zeros(iter);
                wiper_ang = zeros(iter);
                theta_value = zeros(iter);
                
                for t = 1:iter % t is in 10^-1s
                    theta = (t/10)*w;
                    
                    theta_value(t) = theta; 
                    time(t) = t/10;
                    
                    % the point P2
                    P2 = a*[cos(theta) sin(theta)];
                    
                    % angle beta
                    beta = asin((a*sin(theta))/b);
                    % the point P3
                    P3 = [((a*cos(theta)) + (b*cos(beta))) 0];
                    P4 = [((a*cos(theta)) + (b*cos(beta)) + base_val) 0];
                    B1 = [((a*cos(theta)) + (b*cos(beta)) + buffer) 0];
                    B2 = [((a*cos(theta)) + (b*cos(beta)) + buffer + width) 0];
                    
                    % wiper position
                    I1 = H1 - B1;
                    W1_ang = atan(I1(1)/I1(2));
                    W1 = H1 + l*[sin(W1_ang) cos(W1_ang)];
                    wiper_ang(t) = W1_ang;
                    
                    I2 = H2 - B2;
                    W2_ang = atan(I2(1)/I2(2));
                    W2 = H2 + l*[sin(W2_ang) cos(W2_ang)];
                    
                    
                    % crank line
                    crank = line([P1(1) P2(1)],[P1(2) P2(2)],'Color','black','LineWidth',3);
                    % slider line
                    slider = line([P2(1) P3(1)],[P2(2) P3(2)],'Color','black','LineWidth',3);
                    % base line
                    base = line([P3(1) P4(1)],[P3(2) P4(2)],'Color','black','LineWidth',3);
                    
                    
                    % wipers
                    wiper1 = line([B1(1) W1(1)],[B1(2) W1(2)],'Color','black','LineWidth',2);
                    wiper2 = line([B2(1) W2(1)],[B2(2) W2(2)],'Color','black','LineWidth',2);
                    
                    % highlighting P1 and P2
                    P1_circ = viscircles(P1,0.1);
                    P2_circ = viscircles(P2,0.1);
                    P3_circ = viscircles(P3,0.1);
                    P4_circ = viscircles(P4,0.1);
                    B1_circ = viscircles(B1,0.1);
                    B2_circ = viscircles(B2,0.1);
                    W1_circ = viscircles(W1,0.1);
                    W2_circ = viscircles(W2,0.1);
                    
                    % the interval to update the plot 
                    pause(0.001);
                    
                    % delete the previous instantances
                    delete(crank);
                    delete(slider);
                    delete(base);
                    delete(wiper1);
                    delete(wiper2);
                    delete(P1_circ);
                    delete(P2_circ);
                    delete(P3_circ);
                    delete(P4_circ);
                    delete(B1_circ);
                    delete(B2_circ);
                    delete(W1_circ);
                    delete(W2_circ);
                end
                
                delete(P2_traj);
                delete(H1_circ);
                delete(H2_circ);
                delete(PER1);
                delete(PEL1);
                delete(PER2);
                delete(PEL2);
                delete(PM1);
                delete(PM2);
                
                close all;
                pause(0.5);
                
                
                graph1 = app.ThetavstimeCheckBox.Value;
                if(graph1 == 1)
                    theta_value = rad2deg(theta_value);
                    
                    figure
                    axis(gca, 'equal');
                    plot(time, theta_value, 'Color', 'red', "LineWidth", 3);
                    title('Theta vs Time');
                    xlabel('Time in seconds');
                    ylabel('Theta in degrees');
                    pause(2);
                end
                
                graph2 = app.AngularVelocityvstimeCheckBox.Value;
                if(graph2 == 1)
                    figure
                    axis(gca, 'equal');
                    plot(time, w_value, 'Color', 'black', "LineWidth", 3);
                    title('Angular Velocity vs Time');
                    xlabel('Time in seconds');
                    ylabel('Angular Velocity in radians/second');
                    pause(2)
                end
                
                graph3 = app.WiperanglevstimeCheckBox.Value;
                if(graph3 == 1)
                    wiper_ang = rad2deg(wiper_ang);
                    
                    figure
                    axis(gca, 'equal');
                    plot(time, wiper_ang, 'Color', 'blue', "LineWidth", 3);
                    title('Wiper angle vs Time');
                    xlabel('Time in seconds');
                    ylabel('Angular Velocity in degrees');
                    pause(2);
                end


                app.OUTPUTTextArea.Value = 'Simulation ran successfully';
                pause(3);
                app.OUTPUTTextArea.Value = '';
        end

        % Value changed function: AngularVelocityEditField
        function AngularVelocityEditFieldValueChanged(app, event)
            % value = app.AngularVelocityEditField.Value;
        end

        % Callback function
        function UnitButtonGroupSelectionChanged(app, event)
            % deleted this button group
        end

        % Value changed function: CranklinklengthEditField
        function CranklinklengthEditFieldValueChanged(app, event)
            % value = app.CranklinklengthEditField.Value;
        end

        % Value changed function: ConnectinglinklengthEditField
        function ConnectinglinklengthEditFieldValueChanged(app, event)
            % value = app.ConnectinglinklengthEditField.Value;
        end

        % Value changed function: WiperlengthsEditField
        function WiperlengthsEditFieldValueChanged(app, event)
            % value = app.WiperlengthsEditField.Value;
        end

        % Value changed function: wiperangleonesideindegreesSlider
        function wiperangleonesideindegreesSliderValueChanged(app, event)
            % value = app.wiperangleonesideindegreesSlider.Value;
        end

        % Value changed function: SliderbaselinklengthEditField
        function SliderbaselinklengthEditFieldValueChanged(app, event)
            % value = app.SliderbaselinklengthEditField.Value;
        end

        % Value changed function: DistancebtwwipersEditField
        function DistancebtwwipersEditFieldValueChanged(app, event)
            % value = app.DistancebtwwipersEditField.Value;
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            app.OUTPUTTextArea.Value = '';
            app.filenameEditField.Value = '';
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            error = false;
            
            base_val = app.SliderbaselinklengthEditField.Value;
            width = app.DistancebtwwipersEditField.Value;
            a = app.CranklinklengthEditField.Value;      
            b = app.ConnectinglinklengthEditField.Value;
            wip_range = app.wiperangleonesideindegreesSlider.Value;
            w = app.AngularVelocityEditField.Value;
            
            if(width > base_val)
                app.OUTPUTTextArea.Value = ['ERROR : Cannot design physical system with given parameters                          ' ...
                    'Recommed to use smaller value for width'];
                error = true;
            end
            
            if(a>=b)
               app.OUTPUTTextArea.Value = ['ERROR : Cannot design physical system with given parameters                          ' ...
                    'Recommed to use larger value of connecting link/ smaller value of crank link'];
                error = true; 
            end
            
            if(wip_range<50)
               app.OUTPUTTextArea.Value = ['WARNING: non-ideal values of hinge height will be obtained                          ' ...
                    'Recommed to use larger value wiper angle'];
               pause(2)
            end
            
            if(w == 0)
                app.OUTPUTTextArea.Value = ['WARNING: cannot observe any motion because w = 0                          ' ...
                    'Recommed to use w != 0'];
               pause(2)
            end
            %
            %
            if(error == false)
               app.ValidLamp.Color = 'g';
               app.OUTPUTTextArea.Value = 'Proceed to run simulation';
               app.RunButton.Enable = true;
               pause(3);
               app.ValidLamp.Color = 'y';
               app.RunButton.Enable = false;
            elseif(error == true)
               app.ValidLamp.Color = 'r';
               pause(3);
               app.ValidLamp.Color = 'y';
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            a = app.CranklinklengthEditField.Value;
            b = app.ConnectinglinklengthEditField.Value;
            base_val = app.SliderbaselinklengthEditField.Value;
            width = app.DistancebtwwipersEditField.Value;
            l = app.WiperlengthsEditField.Value;
            wip_range = app.wiperangleonesideindegreesSlider.Value;
            T = app.TimeofexecutionsecSlider.Value;
            w = app.AngularVelocityEditField.Value;
            graph1 = app.ThetavstimeCheckBox.Value;
            graph2 = app.AngularVelocityvstimeCheckBox.Value;
            graph3 = app.WiperanglevstimeCheckBox.Value;
            
            % Create a table with the data and variable names
            Table = table(a, b, base_val, width, l, wip_range, T, w, graph1, graph2, graph3, ...
                'VariableNames', { 'crank length', 'connencting link length', 'slider link length', ...
                'wiper width', 'wiper length', 'wiper angle range', 'time of execution', ...
                'angular velocity', 'graph1', 'graph2', 'graph3'});
            value = app.filenameEditField.Value;
            filename = value;

            % Write data to text file
            writetable(Table, filename);
        end

        % Value changed function: filenameEditField
        function filenameEditFieldValueChanged(app, event)
            %value = app.filenameEditField.Value;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            value = app.filenameEditField.Value;
            filename = value;
            
            [num,txt,raw] = xlsread(filename);
            app.CranklinklengthEditField.Value = num(1);
            app.ConnectinglinklengthEditField.Value = num(2);
            app.SliderbaselinklengthEditField.Value = num(3);
            app.DistancebtwwipersEditField.Value = num(4);
            app.WiperlengthsEditField.Value = num(5);
            app.wiperangleonesideindegreesSlider.Value = num(6);
            app.TimeofexecutionsecSlider.Value = num(7);
            app.AngularVelocityEditField.Value = num(8);
            app.ThetavstimeCheckBox.Value = num(9);
            app.AngularVelocityvstimeCheckBox.Value = num(10);
            app.WiperanglevstimeCheckBox.Value = num(11);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeofexecutionsecSliderLabel = uilabel(app.UIFigure);
            app.TimeofexecutionsecSliderLabel.Text = 'Time of execution (sec)';
            app.TimeofexecutionsecSlider = uislider(app.UIFigure);
            app.TimeofexecutionsecSlider.Limits = [1 60];
            app.TimeofexecutionsecSlider.ValueChangedFcn = createCallbackFcn(app, @TimeofexecutionsecSliderValueChanged, true);
            app.TimeofexecutionsecSlider.Value = 5;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.EvaluateButton = uibutton(app.UIFigure, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.AngularVelocityLabel = uilabel(app.UIFigure);
            app.AngularVelocityLabel.Text = 'Angular Velocity         ';
            app.AngularVelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngularVelocityEditField.ValueChangedFcn = createCallbackFcn(app, @AngularVelocityEditFieldValueChanged, true);
            app.AngularVelocityEditField.Value = 2;
            app.CranklinklengthLabel = uilabel(app.UIFigure);
            app.CranklinklengthLabel.Text = 'Crank link length        ';
            app.CranklinklengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.CranklinklengthEditField.Limits = [0 Inf];
            app.CranklinklengthEditField.ValueChangedFcn = createCallbackFcn(app, @CranklinklengthEditFieldValueChanged, true);
            app.CranklinklengthEditField.Value = 1;
            app.ConnectinglinklengthEditFieldLabel = uilabel(app.UIFigure);
            app.ConnectinglinklengthEditFieldLabel.Text = 'Connecting link length';
            app.ConnectinglinklengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConnectinglinklengthEditField.Limits = [0 Inf];
            app.ConnectinglinklengthEditField.ValueChangedFcn = createCallbackFcn(app, @ConnectinglinklengthEditFieldValueChanged, true);
            app.ConnectinglinklengthEditField.Value = 2;
            app.WiperlengthsLabel = uilabel(app.UIFigure);
            app.WiperlengthsLabel.Text = 'Wiper lengths             ';
            app.WiperlengthsEditField = uieditfield(app.UIFigure, 'numeric');
            app.WiperlengthsEditField.Limits = [0 Inf];
            app.WiperlengthsEditField.ValueChangedFcn = createCallbackFcn(app, @WiperlengthsEditFieldValueChanged, true);
            app.WiperlengthsEditField.Value = 2;
            app.wiperangleonesideindegreesSliderLabel = uilabel(app.UIFigure);
            app.wiperangleonesideindegreesSliderLabel.Text = 'wiper angle (one side,in degrees)';
            app.wiperangleonesideindegreesSlider = uislider(app.UIFigure);
            app.wiperangleonesideindegreesSlider.Limits = [10 70];
            app.wiperangleonesideindegreesSlider.ValueChangedFcn = createCallbackFcn(app, @wiperangleonesideindegreesSliderValueChanged, true);
            app.wiperangleonesideindegreesSlider.Value = 60;
            app.SliderbaselinklengthEditFieldLabel = uilabel(app.UIFigure);
            app.SliderbaselinklengthEditFieldLabel.Text = 'Slider /base link length         ';
            app.SliderbaselinklengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SliderbaselinklengthEditField.Limits = [0 Inf];
            app.SliderbaselinklengthEditField.ValueChangedFcn = createCallbackFcn(app, @SliderbaselinklengthEditFieldValueChanged, true);
            app.SliderbaselinklengthEditField.Value = 3;
            app.DistancebtwwipersEditFieldLabel = uilabel(app.UIFigure);
            app.DistancebtwwipersEditFieldLabel.Text = 'Distance btw wipers   ';
            app.DistancebtwwipersEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancebtwwipersEditField.Limits = [0 Inf];
            app.DistancebtwwipersEditField.ValueChangedFcn = createCallbackFcn(app, @DistancebtwwipersEditFieldValueChanged, true);
            app.DistancebtwwipersEditField.Value = 1;
            app.WIPERMECHANISMLabel = uilabel(app.UIFigure);
            app.WIPERMECHANISMLabel.Text = ' WIPER MECHANISM';
            app.ValidLampLabel = uilabel(app.UIFigure);
            app.ValidLampLabel.Text = 'Valid';
            app.ValidLamp = uilamp(app.UIFigure);
            app.OUTPUTTextAreaLabel = uilabel(app.UIFigure);
            app.OUTPUTTextAreaLabel.Text = 'OUTPUT';
            app.OUTPUTTextArea = uitextarea(app.UIFigure);
            app.OUTPUTTextArea.Editable = 'off';
            app.CLEARButton = uibutton(app.UIFigure, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.AngularVelocityvstimeCheckBox = uicheckbox(app.UIFigure);
            app.AngularVelocityvstimeCheckBox.Text = 'Angular Velocity vs time';
            app.AngularVelocityvstimeCheckBox.Value = true;
            app.WiperanglevstimeCheckBox = uicheckbox(app.UIFigure);
            app.WiperanglevstimeCheckBox.Text = 'Wiper angle vs time';
            app.WiperanglevstimeCheckBox.Value = true;
            app.ThetavstimeCheckBox = uicheckbox(app.UIFigure);
            app.ThetavstimeCheckBox.Text = 'Theta vs time';
            app.ThetavstimeCheckBox.Value = true;
            app.LinklengethsmLabel = uilabel(app.UIFigure);
            app.LinklengethsmLabel.Text = ' Link lengeths (m)';
            app.GraphsLabel = uilabel(app.UIFigure);
            app.GraphsLabel.Text = 'Graphs :';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.filenameEditFieldLabel = uilabel(app.UIFigure);
            app.filenameEditFieldLabel.Text = 'file name:';
            app.filenameEditField = uieditfield(app.UIFigure, 'text');
            app.filenameEditField.ValueChangedFcn = createCallbackFcn(app, @filenameEditFieldValueChanged, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Wiper_Mechanism

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end